classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_Input                       matlab.ui.container.Tab
        BrowseButton                    matlab.ui.control.Button
        ChooseplatemapEditField         matlab.ui.control.EditField
        TabGroup3                       matlab.ui.container.TabGroup
        Plate1Tab                       matlab.ui.container.Tab
        PathtoImagesEditField           matlab.ui.control.EditField
        UITable                         matlab.ui.control.Table
        UITable_2                       matlab.ui.control.Table
        PathtoImagesLabel               matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        RowsLabel                       matlab.ui.control.Label
        ColumnsLabel                    matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        EditField_3                     matlab.ui.control.EditField
        FieldsLabel_2                   matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        TimepointsLabel_3               matlab.ui.control.Label
        Button_29                       matlab.ui.control.Button
        PlateMetadataLabel              matlab.ui.control.Label
        PlateMapLabel_2                 matlab.ui.control.Label
        FilterInputLabel_2              matlab.ui.control.Label
        ImageChannelsLabel              matlab.ui.control.Label
        Button_30                       matlab.ui.control.Button
        Button_31                       matlab.ui.control.Button
        Button_32                       matlab.ui.control.Button
        ListBox_2                       matlab.ui.control.ListBox
        NumberofImagesLabel             matlab.ui.control.Label
        EditField_5                     matlab.ui.control.EditField
        ZSlicesEditField                matlab.ui.control.EditField
        ZSlicesLabel                    matlab.ui.control.Label
        Button_BrowseHelp               matlab.ui.control.Button
        OpenProjectFileLabel            matlab.ui.control.Label
        CheckBox_AnalyzeImmediately     matlab.ui.control.CheckBox
        Tab_Display                     matlab.ui.container.Tab
        DisplaySettingsLabel            matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ImageSelectionLabel             matlab.ui.control.Label
        ChannelsLabel                   matlab.ui.control.Label
        SegmentOverlayLabel             matlab.ui.control.Label
        MetricOverlayLabel              matlab.ui.control.Label
        Button_3                        matlab.ui.control.Button
        Button_16                       matlab.ui.control.Button
        Button_17                       matlab.ui.control.Button
        Button_18                       matlab.ui.control.Button
        ColumnDropDownLabel             matlab.ui.control.Label
        ColumnDropDown                  matlab.ui.control.DropDown
        FieldDropDownLabel              matlab.ui.control.Label
        TimepointDropDownLabel          matlab.ui.control.Label
        FieldDropDown                   matlab.ui.control.DropDown
        TimepointDropDown               matlab.ui.control.DropDown
        Button_SaveFigure               matlab.ui.control.Button
        RowDropDownLabel                matlab.ui.control.Label
        RowDropDown                     matlab.ui.control.DropDown
        ExperimentDropDownLabel         matlab.ui.control.Label
        ExperimentDropDown              matlab.ui.control.DropDown
        PlateDropDownLabel              matlab.ui.control.Label
        PlateDropDown                   matlab.ui.control.DropDown
        AtMagnificationSpinnerLabel     matlab.ui.control.Label
        AtMagnificationSpinner          matlab.ui.control.Spinner
        DisplayMeasureDropDown          matlab.ui.control.DropDown
        DisplayMeasureCheckBox          matlab.ui.control.CheckBox
        DisplayMeasureColorButton       matlab.ui.control.Button
        DisplayMeasureFontSize          matlab.ui.control.Spinner
        Button_ViewOverlaidMeasurements  matlab.ui.control.Button
        ZSliceDropDownLabel             matlab.ui.control.Label
        ZSliceDropDown                  matlab.ui.control.DropDown
        Tab_Preprocess                  matlab.ui.container.Tab
        PreprocessInputImagesLabel      matlab.ui.control.Label
        Button_AddProprocess            matlab.ui.control.Button
        AddapreprocesssteptogetstartedLabel  matlab.ui.control.Label
        Tab_Segment                     matlab.ui.container.Tab
        Button_AddSegment               matlab.ui.control.Button
        SegmentationSettingsLabel_2     matlab.ui.control.Label
        AddasegmenttogetstartedLabel    matlab.ui.control.Label
        Tab_Measure                     matlab.ui.container.Tab
        Button_StartProcessing          matlab.ui.control.Button
        ProcessingLogTextArea           matlab.ui.control.TextArea
        PrimarySegmentDropDownLabel     matlab.ui.control.Label
        PrimarySegmentDropDown          matlab.ui.control.DropDown
        SelectMeasurementsLabel         matlab.ui.control.Label
        Button_PrimarySegmentHelp       matlab.ui.control.Button
        Button_AddMeasure               matlab.ui.control.Button
        CheckBox_Parallel               matlab.ui.control.CheckBox
        CheckBox_TestRun                matlab.ui.control.CheckBox
        Button_ViewMeasurements         matlab.ui.control.Button
        Button_ExportMeasurements       matlab.ui.control.Button
        AddameasurementtogetstartedLabel  matlab.ui.control.Label
        SavetoEditField                 matlab.ui.control.EditField
        SavetoEditFieldLabel            matlab.ui.control.Label
        AutoSaveMeasurementsHelp        matlab.ui.control.Button
        RemoveSecondarySegments_CheckBox  matlab.ui.control.CheckBox
        RemovePrimarySegments_CheckBox  matlab.ui.control.CheckBox
        RemovePrimarySegmentsOutside    matlab.ui.control.DropDown
        ParallelWorkersField            matlab.ui.control.NumericEditField
        PrimarySegmentDropDownLabel_2   matlab.ui.control.Label
        CheckBox_EvaluateSegmentation   matlab.ui.control.CheckBox
        Tab_Track                       matlab.ui.container.Tab
        TrackMeasuresListBox            matlab.ui.control.ListBox
        ListBox_3Label                  matlab.ui.control.Label
        TrackObjectsLabel               matlab.ui.control.Label
        Button_StartProcessing_2        matlab.ui.control.Button
        ChooseTrackingMeasurementsHelpButton  matlab.ui.control.Button
        Tab_Filter                      matlab.ui.container.Tab
        AnalyzeLabel_2                  matlab.ui.control.Label
        FilterSettingsLabel             matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        NumberBeforeFilteringEditFieldLabel  matlab.ui.control.Label
        NumberBeforeFiltering           matlab.ui.control.NumericEditField
        NumberAfterFilteringLabel       matlab.ui.control.Label
        NumberAfterFiltering            matlab.ui.control.NumericEditField
        KeepFirstEditFieldLabel         matlab.ui.control.Label
        FilterKeepFirst                 matlab.ui.control.NumericEditField
        KeepLastEditFieldLabel          matlab.ui.control.Label
        FilterKeepLast                  matlab.ui.control.NumericEditField
        SortByDropDownLabel             matlab.ui.control.Label
        FilterSortByDropDown            matlab.ui.control.DropDown
        FiltersTextArea                 matlab.ui.control.TextArea
        HowToPanel                      matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        FiltersTextAreaLabel            matlab.ui.control.Label
        FiltersTextAreaLabel_2          matlab.ui.control.Label
        FilterReductionTextArea         matlab.ui.control.TextArea
        Button_ViewFilteredData         matlab.ui.control.Button
        FiltersTextAreaLabel_3          matlab.ui.control.Label
        Button_AddSegment_2             matlab.ui.control.Button
        Tab_Analyze                     matlab.ui.container.Tab
        AnalyzeYourResultsLabel         matlab.ui.control.Label
        AddananalysistogetstartedLabel  matlab.ui.control.Label
        Button_AddAnalysis              matlab.ui.control.Button
        Button_RunAllAnalysis           matlab.ui.control.Button
        Button_LoadData                 matlab.ui.control.Button
        onDropDownLabel                 matlab.ui.control.Label
        onDropDown                      matlab.ui.control.DropDown
        SegmentLabel                    matlab.ui.control.Label
        MadewithinTorontoLabel          matlab.ui.control.Label
        Button_Segment                  matlab.ui.control.Button
        MeasureLabel                    matlab.ui.control.Label
        TrackLabel                      matlab.ui.control.Label
        AnalyzeLabel                    matlab.ui.control.Label
        InputLabel                      matlab.ui.control.Label
        Button_Input                    matlab.ui.control.Button
        Button_Plot                     matlab.ui.control.Button
        Button_Track                    matlab.ui.control.Button
        Button_Measure                  matlab.ui.control.Button
        SickKidsAILibraryLabel          matlab.ui.control.Label
        KafriLabLabel                   matlab.ui.control.Label
        Button_Plot_2                   matlab.ui.control.Button
        DisplayLabel                    matlab.ui.control.Label
        Button_Save_Application         matlab.ui.control.Button
        PreprocessLabel                 matlab.ui.control.Label
        Button_Preprocess               matlab.ui.control.Button
        Delete_Unicode                  matlab.ui.control.Button
        donotdeleteLabel                matlab.ui.control.Label
        Button_Filter                   matlab.ui.control.Button
        FilterLabel                     matlab.ui.control.Label
        BusyLamp                        matlab.ui.control.Lamp
        BusyLampLabel                   matlab.ui.control.Label
        ProgressSlider                  matlab.ui.control.Slider
        DocumentationButton             matlab.ui.control.StateButton
        BusyLampLabel_2                 matlab.ui.control.Label
        Button_SaveFigure_2             matlab.ui.control.Button
        MontageMovieCheckBox            matlab.ui.control.CheckBox
        MovieatFPSSpinnerLabel          matlab.ui.control.Label
        MovieatFPSSpinner               matlab.ui.control.Spinner
        ListBox_3Label_2                matlab.ui.control.Label
        Button_PrimarySegmentHelp_3     matlab.ui.control.Button
        ListBox_3Label_3                matlab.ui.control.Label
        TimeColumnDropDown              matlab.ui.control.DropDown
        TimeColumnHelpButton            matlab.ui.control.Button
        EditField_6Label                matlab.ui.control.Label
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_7Label                matlab.ui.control.Label
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_8Label                matlab.ui.control.Label
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_9Label                matlab.ui.control.Label
        EditField_9                     matlab.ui.control.NumericEditField
        fromTracktabLabel               matlab.ui.control.Label
        fromDisplaytabLabel             matlab.ui.control.Label
        Button_Track_2                  matlab.ui.control.Button
        OrganizingtheworldsscientificalgorithmsLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        mainDir
        image % new currently displayed image
        image_info
        input_data
        plates
        image_names
        log_processing_message
        log_startup_message
        StartupLogTextArea
        segment
        segment_tabgp
        segment_names
        display
        measure
        measure_tabgp
        measure_overlay_color
        measure_overlay_size
        ResultTable
        ResultTable_for_display
        ResultTable_filtered
        preprocess
        preprocess_tabgp
        analyze
        analyze_tabgp
        ProcessingLogQueue
        processing_running
        progressdlg
        progressdlg2
        images_full_path
        bioformat_data
        exe_mode
        preprocess_plugin_definitions
        segment_plugin_definitions
        measure_plugin_definitions
        analyze_plugin_definitions
        measure_snapshot_selection
        current_image_name
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('functions'));
            addpath(genpath('plugins'));
            startup_fnc(app,@createCallbackFcn);
            %delete(startup_f); % it's not working. Refactor the startup log into the platemap loading log and display the log textbox in the input tab while loading and delete when done. Right now it only displays after it's useful, after the loading is done
             
        end

        % Callback function
        function UITableCellEdit(app, event)
            update_plot(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            c = uisetcolor([1 1 0],'Select a color');
        end

        % Button pushed function: Button_Segment
        function Button_SegmentPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Segment;
        end

        % Button pushed function: Button_Input
        function Button_InputPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Input;
        end

        % Button pushed function: Button_Measure
        function Button_MeasurePushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Measure;
        end

        % Button pushed function: Button_Track
        function Button_TrackPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Track;
            %uialert(app.UIFigure,'Sorry, this feature has not yet been implemented.', 'Feature Not Implemented', 'Icon','warning');
        end

        % Button pushed function: Button_Plot
        function Button_PlotPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Analyze;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)

            browse_button_pushed(app, @createCallbackFcn);
        end

        % Button pushed function: Button_StartProcessing
        function Button_StartProcessingPushed(app, event)
            start_processing_button_pushed(app);
        end

        % Button pushed function: Button_Plot_2
        function Button_Display_Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Display;
            if ~isempty(app.input_data)
              update_figure(app);
            end
        end

        % Button pushed function: Button_AddSegment
        function Button_AddSegmentPushed(app, event)
            add_segment(app,@createCallbackFcn);
        end

        % Value changed function: PlateDropDown
        function PlateDropDownValueChanged(app, event)
            changed_PlateDropDown(app);
        end

        % Value changed function: RowDropDown
        function RowDropDownValueChanged(app, event)
            changed_RowColumnFieldTimepoint_DropDown(app);
        end

        % Value changed function: ColumnDropDown
        function ColumnDropDownValueChanged(app, event)
            changed_RowColumnFieldTimepoint_DropDown(app);
        end

        % Value changed function: FieldDropDown
        function FieldDropDownValueChanged(app, event)
            changed_RowColumnFieldTimepoint_DropDown(app);
        end

        % Value changed function: TimepointDropDown
        function TimepointDropDownValueChanged(app, event)
            changed_RowColumnFieldTimepoint_DropDown(app);
        end

        % Button pushed function: Button_SaveFigure
        function Button_SaveFigurePushed(app, event)
            save_dir = uigetdir(app.ChooseplatemapEditField.Value,'Select Directory to Save Figure In');
            date_str = datestr(now,'yyyymmddTHHMMSS');
            filename = [save_dir '/figure_' date_str '.png'];
            mag = num2str(app.AtMagnificationSpinner.Value);
            export_fig(filename, ['-m' mag]);
            myicon = imread(filename);
            msgbox(sprintf('Saved the current figure to the file "%s" at %sx the resolution that see on your screen.',filename,mag),'Success','custom',myicon);
        end

        % Button pushed function: Button_AddMeasure
        function Button_AddMeasurePushed(app, event)
            add_measure(app, @createCallbackFcn);
        end

        % Button pushed function: Button_BrowseHelp
        function Button_BrowseHelpPushed(app, event)
            message = sprintf('You must choose a plate map to describe your experiment. It is a spreadsheet the contains the location to images, the channel names, the plate well layout of your experiments, and any other metadata that you choose to include.');
            uialert(app.UIFigure,message,'Browse a plate map',...
            'Icon','info');
        end

        % Button pushed function: Button_PrimarySegmentHelp
        function Button_PrimarySegmentHelpPushed(app, event)
            msg = 'Importantly, measurements are collected for each primary segment. Typically a cell would be a primary segment and a nucleus would be a sub-segment. This means that in the result table, each row is in the table corrosponds to one primary segment (ie. a cell). Measurements of sub-segments are stored as columns in the row of the primary segment. When measurement algorithms are configured in a way that produces of different number of results than the number of primary segments an error message will display. Use ''None'' when your segments do not have a heirarchy of containment, they exist in indepedent locations, and when overlapping is not a must.';
            uialert(app.UIFigure,msg,'Primary Segment', 'Icon','info');
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            update_figure(app);
        end

        % Value changed function: DisplayMeasureCheckBox
        function DisplayMeasureCheckBoxValueChanged(app, event)
            changed_Measure_overlay_CheckBox(app);
        end

        % Button pushed function: DisplayMeasureColorButton
        function DisplayMeasureColorButtonPushed(app, event)
            %app.display.channel_override = false;
            current_RGB = app.measure_overlay_color;
            new_RGB = uisetcolor(current_RGB);
            app.measure_overlay_color = new_RGB;
            update_figure(app);
        end

        % Value changed function: DisplayMeasureFontSize
        function DisplayMeasureFontSizeValueChanged(app, event)
            value = app.DisplayMeasureFontSize.Value;
            update_figure(app);
        end

        % Value changed function: DisplayMeasureDropDown
        function DisplayMeasureDropDownValueChanged(app, event)
            value = app.DisplayMeasureDropDown.Value;
            app.DisplayMeasureCheckBox.Value = true;
            update_figure(app);
        end

        % Callback function
        function Button_33Pushed(app, event)
            uialert(app.UIFigure,'test','Unkown image naming scheme', 'Icon','error');
            app.processing_running = false;
        end

        % Button pushed function: Button_ViewMeasurements
        function Button_ViewMeasurementsPushed(app, event)
            assignin('base','ResultTable',app.ResultTable);
            evalin('base','openvar(''ResultTable'')');
        end

        % Button pushed function: Button_ExportMeasurements
        function Button_ExportMeasurementsPushed(app, event)
            save_measurements(app, 'prompt_file_type','prompt_save_location');
        end

        % Button pushed function: Button_Save_Application
        function Button_Save_ApplicationPushed(app, event)
            save_application_state(app);
        end

        % Button pushed function: Button_Preprocess
        function Button_PreprocessPushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_Preprocess;
        end

        % Button pushed function: Button_AddProprocess
        function Button_AddProprocessPushed(app, event)
            add_preprocess(app,@createCallbackFcn);
        end

        % Value changed function: ExperimentDropDown
        function ExperimentDropDownValueChanged(app, event)
            value = app.ExperimentDropDown.Value;
            changed_ExperimentDropDown(app);
        end

        % Button pushed function: Button_AddAnalysis
        function Button_AddAnalysisPushed(app, event)
            add_analyze(app,@createCallbackFcn);
        end

        % Button pushed function: Button_SaveFigure_2
        function Button_SaveFigure_2Pushed(app, event)
            save_montage(app);
        end

        % Value changed function: MontageMovieCheckBox
        function MontageMovieCheckBoxValueChanged(app, event)
            value = app.MontageMovieCheckBox.Value;
            if value
                app.MovieatFPSSpinner.Enable = 'on';
                app.MovieatFPSSpinnerLabel.Enable = 'on';
            else
                app.MovieatFPSSpinner.Enable = 'off';
                app.MovieatFPSSpinnerLabel.Enable = 'off';
            end
        end

        % Button pushed function: Button_RunAllAnalysis
        function Button_RunAllAnalysisPushed(app, event)
            run_all_analysis(app);
        end

        % Button pushed function: Button_ViewOverlaidMeasurements
        function Button_ViewOverlaidMeasurementsPushed(app, event)
            assignin('base','Overlaid_ResultTable',app.ResultTable_for_display);
            evalin('base','openvar(''Overlaid_ResultTable'')');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            FigureCloseRequest(app);
            delete(app);
        end

        % Button pushed function: Button_LoadData
        function Button_LoadDataPushed(app, event)
            load_data_button_pushed(app);
        end

        % Button pushed function: Button_Filter
        function Button_FilterPushed(app, event)
           app.TabGroup.SelectedTab = app.Tab_Filter; 
        end

        % Value changed function: FiltersTextArea
        function FiltersTextAreaValueChanged(app, event)
            changed_FilterResults(app);
        end

        % Value changed function: FilterSortByDropDown
        function FilterSortByDropDownValueChanged(app, event)
            changed_FilterResults(app);
        end

        % Value changed function: FilterKeepFirst
        function FilterKeepFirstValueChanged(app, event)
            changed_FilterResults(app);
        end

        % Value changed function: FilterKeepLast
        function FilterKeepLastValueChanged(app, event)
            changed_FilterResults(app);
        end

        % Button pushed function: Button_AddSegment_2
        function Button_AddSegment_2Pushed(app, event)
            reset_FilterResults(app);
        end

        % Button pushed function: Button_ViewFilteredData
        function Button_ViewFilteredDataPushed(app, event)
            assignin('base','ResultTable_filtered',app.ResultTable_filtered);
            evalin('base','openvar(''ResultTable_filtered'')');
        end

        % Button pushed function: Button_StartProcessing_2
        function StartTrackingButtonPushed(app, event)
            start_tracking_button_pushed(app);
        end

        % Button pushed function: TimeColumnHelpButton
        function TimeColumnHelpButtonPushed(app, event)
            msg = 'Choose the column in the measurements table that denotes time passing. Tracking objects over time is the objective.';
            uialert(app.UIFigure,msg,'Time Column', 'Icon','info');
        end

        % Button pushed function: 
        % ChooseTrackingMeasurementsHelpButton
        function ChooseTrackingMeasurementsHelpButtonPushed(app, event)
            msg = 'Choose the columns in the measurements table that will be used for tracking objects. Objects with the most similar measurements over time are considered the same.';
            uialert(app.UIFigure,msg,'Tracking Measurements', 'Icon','info');
        end

        % Value changed function: ZSliceDropDown
        function ZSliceDropDownValueChanged(app, event)
            value = app.ZSliceDropDown.Value;
            changed_ZSliceDropDown(app);
        end

        % Value changed function: PrimarySegmentDropDown
        function PrimarySegmentDropDownValueChanged(app, event)
            changed_primary_segment(app);
        end

        % Value changed function: CheckBox_Parallel
        function CheckBox_ParallelValueChanged(app, event)
            changed_parallel_proc_checkbox(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_Input = uitab(app.TabGroup);
            app.Tab_Input.Title = '                       ';
            app.BrowseButton = uibutton(app.Tab_Input, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ChooseplatemapEditField = uieditfield(app.Tab_Input, 'text');
            app.ChooseplatemapEditField.Editable = 'off';
            app.TabGroup3 = uitabgroup(app.Tab_Input);
            app.Plate1Tab = uitab(app.TabGroup3);
            app.Plate1Tab.Title = 'Plate 1';
            app.PathtoImagesEditField = uieditfield(app.Plate1Tab, 'text');
            app.PathtoImagesEditField.Editable = 'off';
            app.UITable = uitable(app.Plate1Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.Plate1Tab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.PathtoImagesLabel = uilabel(app.Plate1Tab);
            app.PathtoImagesLabel.Text = 'Path to Images:';
            app.EditField = uieditfield(app.Plate1Tab, 'text');
            app.RowsLabel = uilabel(app.Plate1Tab);
            app.RowsLabel.Text = 'Rows:';
            app.ColumnsLabel = uilabel(app.Plate1Tab);
            app.ColumnsLabel.Text = 'Columns:';
            app.EditField_2 = uieditfield(app.Plate1Tab, 'text');
            app.EditField_3 = uieditfield(app.Plate1Tab, 'text');
            app.FieldsLabel_2 = uilabel(app.Plate1Tab);
            app.FieldsLabel_2.Text = 'Fields:';
            app.EditField_4 = uieditfield(app.Plate1Tab, 'text');
            app.TimepointsLabel_3 = uilabel(app.Plate1Tab);
            app.TimepointsLabel_3.Text = 'Timepoints:';
            app.Button_29 = uibutton(app.Plate1Tab, 'push');
            app.Button_29.Icon = 'question-sign.png';
            app.Button_29.Text = '';
            app.PlateMetadataLabel = uilabel(app.Plate1Tab);
            app.PlateMetadataLabel.Text = 'Plate Metadata:';
            app.PlateMapLabel_2 = uilabel(app.Plate1Tab);
            app.PlateMapLabel_2.Text = 'Plate Map:';
            app.FilterInputLabel_2 = uilabel(app.Plate1Tab);
            app.FilterInputLabel_2.Text = 'Filter Input:';
            app.ImageChannelsLabel = uilabel(app.Plate1Tab);
            app.ImageChannelsLabel.Text = 'Image Channels';
            app.Button_30 = uibutton(app.Plate1Tab, 'push');
            app.Button_30.Icon = 'question-sign.png';
            app.Button_30.Text = '';
            app.Button_31 = uibutton(app.Plate1Tab, 'push');
            app.Button_31.Icon = 'question-sign.png';
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.Plate1Tab, 'push');
            app.Button_32.Icon = 'question-sign.png';
            app.Button_32.Text = '';
            app.ListBox_2 = uilistbox(app.Plate1Tab);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.NumberofImagesLabel = uilabel(app.Plate1Tab);
            app.NumberofImagesLabel.Text = 'Number of Images:';
            app.EditField_5 = uieditfield(app.Plate1Tab, 'text');
            app.EditField_5.Editable = 'off';
            app.ZSlicesEditField = uieditfield(app.Plate1Tab, 'text');
            app.ZSlicesLabel = uilabel(app.Plate1Tab);
            app.ZSlicesLabel.Text = 'Z Slices:';
            app.Button_BrowseHelp = uibutton(app.Tab_Input, 'push');
            app.Button_BrowseHelp.ButtonPushedFcn = createCallbackFcn(app, @Button_BrowseHelpPushed, true);
            app.Button_BrowseHelp.Icon = 'question-sign.png';
            app.Button_BrowseHelp.Text = '';
            app.OpenProjectFileLabel = uilabel(app.Tab_Input);
            app.OpenProjectFileLabel.Text = 'Open Project File:';
            app.CheckBox_AnalyzeImmediately = uicheckbox(app.Tab_Input);
            app.CheckBox_AnalyzeImmediately.Text = 'Analyze Immediately';
            app.Tab_Display = uitab(app.TabGroup);
            app.Tab_Display.Title = '                       ';
            app.DisplaySettingsLabel = uilabel(app.Tab_Display);
            app.DisplaySettingsLabel.Text = 'Display Settings';
            app.Panel = uipanel(app.Tab_Display);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.ImageSelectionLabel = uilabel(app.Panel);
            app.ImageSelectionLabel.Text = 'Image Selection';
            app.ChannelsLabel = uilabel(app.Panel);
            app.ChannelsLabel.Text = 'Channels';
            app.SegmentOverlayLabel = uilabel(app.Panel);
            app.SegmentOverlayLabel.Text = 'Segment Overlay';
            app.MetricOverlayLabel = uilabel(app.Panel);
            app.MetricOverlayLabel.Text = 'Metric Overlay';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.Icon = 'camera.png';
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.Button_16 = uibutton(app.Panel, 'push');
            app.Button_16.Icon = '010-check.png';
            app.Button_16.Enable = 'off';
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.Panel, 'push');
            app.Button_17.Icon = 'select.png';
            app.Button_17.Enable = 'off';
            app.Button_17.Text = '';
            app.Button_18 = uibutton(app.Panel, 'push');
            app.Button_18.Icon = 'ruler.PNG';
            app.Button_18.Enable = 'off';
            app.Button_18.Text = '';
            app.ColumnDropDownLabel = uilabel(app.Panel);
            app.ColumnDropDownLabel.Text = 'Column';
            app.ColumnDropDown = uidropdown(app.Panel);
            app.ColumnDropDown.Items = {};
            app.ColumnDropDown.Editable = 'on';
            app.ColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @ColumnDropDownValueChanged, true);
            app.ColumnDropDown.Value = {};
            app.FieldDropDownLabel = uilabel(app.Panel);
            app.FieldDropDownLabel.Text = 'Field';
            app.TimepointDropDownLabel = uilabel(app.Panel);
            app.TimepointDropDownLabel.Text = 'Timepoint';
            app.FieldDropDown = uidropdown(app.Panel);
            app.FieldDropDown.Items = {};
            app.FieldDropDown.Editable = 'on';
            app.FieldDropDown.ValueChangedFcn = createCallbackFcn(app, @FieldDropDownValueChanged, true);
            app.FieldDropDown.Value = {};
            app.TimepointDropDown = uidropdown(app.Panel);
            app.TimepointDropDown.Items = {};
            app.TimepointDropDown.Editable = 'on';
            app.TimepointDropDown.ValueChangedFcn = createCallbackFcn(app, @TimepointDropDownValueChanged, true);
            app.TimepointDropDown.Value = {};
            app.Button_SaveFigure = uibutton(app.Panel, 'push');
            app.Button_SaveFigure.ButtonPushedFcn = createCallbackFcn(app, @Button_SaveFigurePushed, true);
            app.Button_SaveFigure.Icon = 'download-to-storage-drive.png';
            app.Button_SaveFigure.Text = 'Save Figure';
            app.RowDropDownLabel = uilabel(app.Panel);
            app.RowDropDownLabel.Text = 'Row';
            app.RowDropDown = uidropdown(app.Panel);
            app.RowDropDown.Items = {};
            app.RowDropDown.Editable = 'on';
            app.RowDropDown.ValueChangedFcn = createCallbackFcn(app, @RowDropDownValueChanged, true);
            app.RowDropDown.Value = {};
            app.ExperimentDropDownLabel = uilabel(app.Panel);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.Panel);
            app.ExperimentDropDown.Items = {};
            app.ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperimentDropDownValueChanged, true);
            app.ExperimentDropDown.Value = {};
            app.PlateDropDownLabel = uilabel(app.Panel);
            app.PlateDropDownLabel.Text = 'Plate';
            app.PlateDropDown = uidropdown(app.Panel);
            app.PlateDropDown.Items = {};
            app.PlateDropDown.ValueChangedFcn = createCallbackFcn(app, @PlateDropDownValueChanged, true);
            app.PlateDropDown.Value = {};
            app.AtMagnificationSpinnerLabel = uilabel(app.Panel);
            app.AtMagnificationSpinnerLabel.Text = 'At Magnification';
            app.AtMagnificationSpinner = uispinner(app.Panel);
            app.AtMagnificationSpinner.Limits = [1 10];
            app.AtMagnificationSpinner.Value = 1;
            app.DisplayMeasureDropDown = uidropdown(app.Panel);
            app.DisplayMeasureDropDown.Items = {};
            app.DisplayMeasureDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayMeasureDropDownValueChanged, true);
            app.DisplayMeasureDropDown.Value = {};
            app.DisplayMeasureCheckBox = uicheckbox(app.Panel);
            app.DisplayMeasureCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayMeasureCheckBoxValueChanged, true);
            app.DisplayMeasureCheckBox.Text = '';
            app.DisplayMeasureColorButton = uibutton(app.Panel, 'push');
            app.DisplayMeasureColorButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayMeasureColorButtonPushed, true);
            app.DisplayMeasureColorButton.Icon = 'painter-palette.png';
            app.DisplayMeasureColorButton.Text = '';
            app.DisplayMeasureFontSize = uispinner(app.Panel);
            app.DisplayMeasureFontSize.Limits = [1 Inf];
            app.DisplayMeasureFontSize.ValueChangedFcn = createCallbackFcn(app, @DisplayMeasureFontSizeValueChanged, true);
            app.DisplayMeasureFontSize.Value = 12;
            app.Button_ViewOverlaidMeasurements = uibutton(app.Panel, 'push');
            app.Button_ViewOverlaidMeasurements.ButtonPushedFcn = createCallbackFcn(app, @Button_ViewOverlaidMeasurementsPushed, true);
            app.Button_ViewOverlaidMeasurements.Icon = 'list.png';
            app.Button_ViewOverlaidMeasurements.Text = 'View Overlaid Data';
            app.ZSliceDropDownLabel = uilabel(app.Panel);
            app.ZSliceDropDownLabel.Text = 'Z Slice';
            app.ZSliceDropDown = uidropdown(app.Panel);
            app.ZSliceDropDown.Items = {};
            app.ZSliceDropDown.Editable = 'on';
            app.ZSliceDropDown.ValueChangedFcn = createCallbackFcn(app, @ZSliceDropDownValueChanged, true);
            app.ZSliceDropDown.Value = {};
            app.Tab_Preprocess = uitab(app.TabGroup);
            app.Tab_Preprocess.Title = '                      ';
            app.PreprocessInputImagesLabel = uilabel(app.Tab_Preprocess);
            app.PreprocessInputImagesLabel.Text = 'Preprocess Input Images';
            app.Button_AddProprocess = uibutton(app.Tab_Preprocess, 'push');
            app.Button_AddProprocess.ButtonPushedFcn = createCallbackFcn(app, @Button_AddProprocessPushed, true);
            app.Button_AddProprocess.Text = '➕ Add Preprocess';
            app.AddapreprocesssteptogetstartedLabel = uilabel(app.Tab_Preprocess);
            app.AddapreprocesssteptogetstartedLabel.Text = 'Add a preprocess step to get started.';
            app.Tab_Segment = uitab(app.TabGroup);
            app.Tab_Segment.Title = '                       ';
            app.Button_AddSegment = uibutton(app.Tab_Segment, 'push');
            app.Button_AddSegment.ButtonPushedFcn = createCallbackFcn(app, @Button_AddSegmentPushed, true);
            app.Button_AddSegment.Text = '➕ Add Segment';
            app.SegmentationSettingsLabel_2 = uilabel(app.Tab_Segment);
            app.SegmentationSettingsLabel_2.Text = 'Segmentation Settings';
            app.AddasegmenttogetstartedLabel = uilabel(app.Tab_Segment);
            app.AddasegmenttogetstartedLabel.Text = 'Add a segment to get started.';
            app.Tab_Measure = uitab(app.TabGroup);
            app.Tab_Measure.Title = '                       ';
            app.Button_StartProcessing = uibutton(app.Tab_Measure, 'push');
            app.Button_StartProcessing.ButtonPushedFcn = createCallbackFcn(app, @Button_StartProcessingPushed, true);
            app.Button_StartProcessing.Icon = 'play-button.png';
            app.Button_StartProcessing.Text = 'Start Processing';
            app.ProcessingLogTextArea = uitextarea(app.Tab_Measure);
            app.ProcessingLogTextArea.Editable = 'off';
            app.PrimarySegmentDropDownLabel = uilabel(app.Tab_Measure);
            app.PrimarySegmentDropDownLabel.Text = 'Primary Segment:';
            app.PrimarySegmentDropDown = uidropdown(app.Tab_Measure);
            app.PrimarySegmentDropDown.Items = {};
            app.PrimarySegmentDropDown.ValueChangedFcn = createCallbackFcn(app, @PrimarySegmentDropDownValueChanged, true);
            app.PrimarySegmentDropDown.Value = {};
            app.SelectMeasurementsLabel = uilabel(app.Tab_Measure);
            app.SelectMeasurementsLabel.Text = 'Select Measurements';
            app.Button_PrimarySegmentHelp = uibutton(app.Tab_Measure, 'push');
            app.Button_PrimarySegmentHelp.ButtonPushedFcn = createCallbackFcn(app, @Button_PrimarySegmentHelpPushed, true);
            app.Button_PrimarySegmentHelp.Icon = 'question-sign.png';
            app.Button_PrimarySegmentHelp.Text = '';
            app.Button_AddMeasure = uibutton(app.Tab_Measure, 'push');
            app.Button_AddMeasure.ButtonPushedFcn = createCallbackFcn(app, @Button_AddMeasurePushed, true);
            app.Button_AddMeasure.Text = '➕ Add Measurement';
            app.CheckBox_Parallel = uicheckbox(app.Tab_Measure);
            app.CheckBox_Parallel.ValueChangedFcn = createCallbackFcn(app, @CheckBox_ParallelValueChanged, true);
            app.CheckBox_Parallel.Text = 'Parallelize';
            app.CheckBox_TestRun = uicheckbox(app.Tab_Measure);
            app.CheckBox_TestRun.Text = 'Only One';
            app.Button_ViewMeasurements = uibutton(app.Tab_Measure, 'push');
            app.Button_ViewMeasurements.ButtonPushedFcn = createCallbackFcn(app, @Button_ViewMeasurementsPushed, true);
            app.Button_ViewMeasurements.Icon = 'list.png';
            app.Button_ViewMeasurements.Text = 'View Measurements';
            app.Button_ExportMeasurements = uibutton(app.Tab_Measure, 'push');
            app.Button_ExportMeasurements.ButtonPushedFcn = createCallbackFcn(app, @Button_ExportMeasurementsPushed, true);
            app.Button_ExportMeasurements.Icon = 'download-to-storage-drive.png';
            app.Button_ExportMeasurements.Text = 'Save Measurements';
            app.AddameasurementtogetstartedLabel = uilabel(app.Tab_Measure);
            app.AddameasurementtogetstartedLabel.Text = 'Add a measurement to get started.';
            app.SavetoEditField = uieditfield(app.Tab_Measure, 'text');
            app.SavetoEditField.Value = 'choose a path';
            app.SavetoEditFieldLabel = uilabel(app.Tab_Measure);
            app.SavetoEditFieldLabel.Text = 'Auto-save measurements to: ';
            app.AutoSaveMeasurementsHelp = uibutton(app.Tab_Measure, 'push');
            app.AutoSaveMeasurementsHelp.Icon = 'question-sign.png';
            app.AutoSaveMeasurementsHelp.Text = '';
            app.RemoveSecondarySegments_CheckBox = uicheckbox(app.Tab_Measure);
            app.RemoveSecondarySegments_CheckBox.Text = 'Remove segments outside primary';
            app.RemovePrimarySegments_CheckBox = uicheckbox(app.Tab_Measure);
            app.RemovePrimarySegments_CheckBox.Text = 'Remove primary outside';
            app.RemovePrimarySegmentsOutside = uidropdown(app.Tab_Measure);
            app.RemovePrimarySegmentsOutside.Items = {};
            app.RemovePrimarySegmentsOutside.Value = {};
            app.ParallelWorkersField = uieditfield(app.Tab_Measure, 'numeric');
            app.ParallelWorkersField.Limits = [1 128];
            app.ParallelWorkersField.RoundFractionalValues = 'on';
            app.ParallelWorkersField.Value = 4;
            app.PrimarySegmentDropDownLabel_2 = uilabel(app.Tab_Measure);
            app.PrimarySegmentDropDownLabel_2.Text = 'Measure each';
            app.CheckBox_EvaluateSegmentation = uicheckbox(app.Tab_Measure);
            app.CheckBox_EvaluateSegmentation.Text = 'Eval Segment';
            app.Tab_Track = uitab(app.TabGroup);
            app.Tab_Track.Title = '                       ';
            app.TrackMeasuresListBox = uilistbox(app.Tab_Track);
            app.TrackMeasuresListBox.Items = {};
            app.TrackMeasuresListBox.Value = {};
            app.ListBox_3Label = uilabel(app.Tab_Track);
            app.ListBox_3Label.Text = 'Choose Measurements:';
            app.TrackObjectsLabel = uilabel(app.Tab_Track);
            app.TrackObjectsLabel.Text = 'Track Objects';
            app.Button_StartProcessing_2 = uibutton(app.Tab_Track, 'push');
            app.Button_StartProcessing_2.ButtonPushedFcn = createCallbackFcn(app, @StartTrackingButtonPushed, true);
            app.Button_StartProcessing_2.Icon = 'play-button.png';
            app.Button_StartProcessing_2.Text = 'Start Tracking';
            app.ChooseTrackingMeasurementsHelpButton = uibutton(app.Tab_Track, 'push');
            app.ChooseTrackingMeasurementsHelpButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseTrackingMeasurementsHelpButtonPushed, true);
            app.ChooseTrackingMeasurementsHelpButton.Icon = 'question-sign.png';
            app.ChooseTrackingMeasurementsHelpButton.Text = '';
            app.Tab_Filter = uitab(app.TabGroup);
            app.Tab_Filter.Title = '                       ';
            app.AnalyzeLabel_2 = uilabel(app.Tab_Filter);
            app.AnalyzeLabel_2.Text = 'Analyze';
            app.FilterSettingsLabel = uilabel(app.Tab_Filter);
            app.FilterSettingsLabel.Text = 'Filter Your Data';
            app.Panel_2 = uipanel(app.Tab_Filter);
            app.NumberBeforeFilteringEditFieldLabel = uilabel(app.Panel_2);
            app.NumberBeforeFilteringEditFieldLabel.Text = 'Number Before Filtering';
            app.NumberBeforeFiltering = uieditfield(app.Panel_2, 'numeric');
            app.NumberBeforeFiltering.Editable = 'off';
            app.NumberAfterFilteringLabel = uilabel(app.Panel_2);
            app.NumberAfterFilteringLabel.Text = 'Number After Filtering';
            app.NumberAfterFiltering = uieditfield(app.Panel_2, 'numeric');
            app.NumberAfterFiltering.Editable = 'off';
            app.KeepFirstEditFieldLabel = uilabel(app.Panel_2);
            app.KeepFirstEditFieldLabel.Text = 'Keep First';
            app.FilterKeepFirst = uieditfield(app.Panel_2, 'numeric');
            app.FilterKeepFirst.Limits = [1 Inf];
            app.FilterKeepFirst.ValueDisplayFormat = '%.0f';
            app.FilterKeepFirst.ValueChangedFcn = createCallbackFcn(app, @FilterKeepFirstValueChanged, true);
            app.FilterKeepFirst.Value = Inf;
            app.KeepLastEditFieldLabel = uilabel(app.Panel_2);
            app.KeepLastEditFieldLabel.Text = 'Keep Last';
            app.FilterKeepLast = uieditfield(app.Panel_2, 'numeric');
            app.FilterKeepLast.Limits = [1 Inf];
            app.FilterKeepLast.ValueDisplayFormat = '%.0f';
            app.FilterKeepLast.ValueChangedFcn = createCallbackFcn(app, @FilterKeepLastValueChanged, true);
            app.FilterKeepLast.Value = Inf;
            app.SortByDropDownLabel = uilabel(app.Panel_2);
            app.SortByDropDownLabel.Text = 'Sort By';
            app.FilterSortByDropDown = uidropdown(app.Panel_2);
            app.FilterSortByDropDown.Items = {};
            app.FilterSortByDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterSortByDropDownValueChanged, true);
            app.FilterSortByDropDown.Value = {};
            app.FiltersTextArea = uitextarea(app.Panel_2);
            app.FiltersTextArea.ValueChangedFcn = createCallbackFcn(app, @FiltersTextAreaValueChanged, true);
            app.HowToPanel = uipanel(app.Panel_2);
            app.HowToPanel.Title = 'How-To';
            app.TextArea = uitextarea(app.HowToPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Filtering data gives you the opportunity to analyze a subset of data by writing your own filter logic using Matlab code. '; ''; 'Filters are written as single lines of text in the "Filters" section. Multiple filters can be entered on different lines and they will all be used together. '; ''; 'An example filter is "Field; Field == 10". This filter is composed of (A) a column name from our table of results data, for example "Field", and (B) a logical operation which can be any valid Matlab code, for example "Field == 10". This example would keep only results that have the column "Field" with the value 10. '; ''; 'Example Filters:'; 'Field; Field == 10    '; 'Time; Time >= 1   '; 'Time; Time <= 10  '; 'Ratio; Ratio ~= Inf'; 'CellLine; strcmp(CellLine, ''Wild Type'')'; 'CellCycleStage; strcmp(CellCycleStage, ''G1'')'; 'NArea; NArea > median(NArea)   '; 'NArea; NArea < prctile(NArea,99.9) '; 'Mitosis; Mitosis == false   '};
            app.FiltersTextAreaLabel = uilabel(app.Panel_2);
            app.FiltersTextAreaLabel.Text = 'Filters';
            app.FiltersTextAreaLabel_2 = uilabel(app.Panel_2);
            app.FiltersTextAreaLabel_2.Text = 'Reduction';
            app.FilterReductionTextArea = uitextarea(app.Panel_2);
            app.FilterReductionTextArea.Editable = 'off';
            app.Button_ViewFilteredData = uibutton(app.Panel_2, 'push');
            app.Button_ViewFilteredData.ButtonPushedFcn = createCallbackFcn(app, @Button_ViewFilteredDataPushed, true);
            app.Button_ViewFilteredData.Icon = 'list.png';
            app.Button_ViewFilteredData.Text = 'View Filtered Data';
            app.FiltersTextAreaLabel_3 = uilabel(app.Panel_2);
            app.FiltersTextAreaLabel_3.Text = 'Keep data given by';
            app.Button_AddSegment_2 = uibutton(app.Tab_Filter, 'push');
            app.Button_AddSegment_2.ButtonPushedFcn = createCallbackFcn(app, @Button_AddSegment_2Pushed, true);
            app.Button_AddSegment_2.Icon = 'undo-arrow_shift_right.png';
            app.Button_AddSegment_2.Text = 'Reset Filter';
            app.Tab_Analyze = uitab(app.TabGroup);
            app.Tab_Analyze.Title = '                      ';
            app.AnalyzeYourResultsLabel = uilabel(app.Tab_Analyze);
            app.AnalyzeYourResultsLabel.Text = 'Analyze Your Results';
            app.AddananalysistogetstartedLabel = uilabel(app.Tab_Analyze);
            app.AddananalysistogetstartedLabel.Text = 'Add an analysis to get started.';
            app.Button_AddAnalysis = uibutton(app.Tab_Analyze, 'push');
            app.Button_AddAnalysis.ButtonPushedFcn = createCallbackFcn(app, @Button_AddAnalysisPushed, true);
            app.Button_AddAnalysis.Text = '➕ Add Analysis';
            app.Button_RunAllAnalysis = uibutton(app.Tab_Analyze, 'push');
            app.Button_RunAllAnalysis.ButtonPushedFcn = createCallbackFcn(app, @Button_RunAllAnalysisPushed, true);
            app.Button_RunAllAnalysis.Icon = 'play-button.png';
            app.Button_RunAllAnalysis.Text = 'Run All Analysis';
            app.Button_LoadData = uibutton(app.Tab_Analyze, 'push');
            app.Button_LoadData.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadDataPushed, true);
            app.Button_LoadData.Icon = 'open-folder-outline.png';
            app.Button_LoadData.Text = 'Load Data';
            app.onDropDownLabel = uilabel(app.Tab_Analyze);
            app.onDropDownLabel.Text = 'on';
            app.onDropDown = uidropdown(app.Tab_Analyze);
            app.onDropDown.Items = {'Current Image', 'All Images'};
            app.onDropDown.Value = 'Current Image';
            app.SegmentLabel = uilabel(app.UIFigure);
            app.SegmentLabel.Text = 'Segment';
            app.MadewithinTorontoLabel = uilabel(app.UIFigure);
            app.MadewithinTorontoLabel.Text = 'Made with ❤️ in Toronto';
            app.Button_Segment = uibutton(app.UIFigure, 'push');
            app.Button_Segment.ButtonPushedFcn = createCallbackFcn(app, @Button_SegmentPushed, true);
            app.Button_Segment.Icon = 'select.png';
            app.Button_Segment.Text = '';
            app.MeasureLabel = uilabel(app.UIFigure);
            app.MeasureLabel.Text = 'Measure';
            app.TrackLabel = uilabel(app.UIFigure);
            app.TrackLabel.Text = 'Track';
            app.AnalyzeLabel = uilabel(app.UIFigure);
            app.AnalyzeLabel.Text = 'Analyze';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.Button_Input = uibutton(app.UIFigure, 'push');
            app.Button_Input.ButtonPushedFcn = createCallbackFcn(app, @Button_InputPushed, true);
            app.Button_Input.Icon = 'open-folder.png';
            app.Button_Input.Text = '';
            app.Button_Plot = uibutton(app.UIFigure, 'push');
            app.Button_Plot.ButtonPushedFcn = createCallbackFcn(app, @Button_PlotPushed, true);
            app.Button_Plot.Icon = 'dot-plot.png';
            app.Button_Plot.Text = '';
            app.Button_Track = uibutton(app.UIFigure, 'push');
            app.Button_Track.ButtonPushedFcn = createCallbackFcn(app, @Button_TrackPushed, true);
            app.Button_Track.Text = '➠';
            app.Button_Measure = uibutton(app.UIFigure, 'push');
            app.Button_Measure.ButtonPushedFcn = createCallbackFcn(app, @Button_MeasurePushed, true);
            app.Button_Measure.Icon = 'ruler.PNG';
            app.Button_Measure.Text = '';
            app.SickKidsAILibraryLabel = uilabel(app.UIFigure);
            app.SickKidsAILibraryLabel.Text = 'SickKids A.I. Library';
            app.KafriLabLabel = uilabel(app.UIFigure);
            app.KafriLabLabel.Text = '@Kafri-Lab 🔬';
            app.Button_Plot_2 = uibutton(app.UIFigure, 'push');
            app.Button_Plot_2.ButtonPushedFcn = createCallbackFcn(app, @Button_Display_Pushed, true);
            app.Button_Plot_2.Icon = 'desktop-monitor (1).png';
            app.Button_Plot_2.Text = '';
            app.DisplayLabel = uilabel(app.UIFigure);
            app.DisplayLabel.Text = 'Display';
            app.Button_Save_Application = uibutton(app.UIFigure, 'push');
            app.Button_Save_Application.ButtonPushedFcn = createCallbackFcn(app, @Button_Save_ApplicationPushed, true);
            app.Button_Save_Application.Icon = 'download-to-storage-drive.png';
            app.Button_Save_Application.Enable = 'off';
            app.Button_Save_Application.Text = 'Save Application State';
            app.PreprocessLabel = uilabel(app.UIFigure);
            app.PreprocessLabel.Text = 'Preprocess';
            app.Button_Preprocess = uibutton(app.UIFigure, 'push');
            app.Button_Preprocess.ButtonPushedFcn = createCallbackFcn(app, @Button_PreprocessPushed, true);
            app.Button_Preprocess.Icon = 'darker-or-brightter-button.png';
            app.Button_Preprocess.Text = '';
            app.Delete_Unicode = uibutton(app.UIFigure, 'push');
            app.Delete_Unicode.Text = '❌';
            app.donotdeleteLabel = uilabel(app.UIFigure);
            app.donotdeleteLabel.Text = '<--do not delete. Needed so that the symbol ''X'' can be used throught the application';
            app.Button_Filter = uibutton(app.UIFigure, 'push');
            app.Button_Filter.ButtonPushedFcn = createCallbackFcn(app, @Button_FilterPushed, true);
            app.Button_Filter.Icon = 'filter-tool-black-shape.png';
            app.Button_Filter.Text = '';
            app.FilterLabel = uilabel(app.UIFigure);
            app.FilterLabel.Text = 'Filter';
            app.BusyLamp = uilamp(app.UIFigure);
            app.BusyLampLabel = uilabel(app.UIFigure);
            app.BusyLampLabel.Text = 'Busy';
            app.ProgressSlider = uislider(app.UIFigure);
            app.ProgressSlider.Limits = [0 1];
            app.ProgressSlider.MajorTicks = [];
            app.ProgressSlider.MajorTickLabels = {};
            app.ProgressSlider.MinorTicks = [];
            app.ProgressSlider.Enable = 'off';
            app.DocumentationButton = uibutton(app.UIFigure, 'state');
            app.DocumentationButton.Icon = 'github.png';
            app.DocumentationButton.Text = 'Documentation';
            app.BusyLampLabel_2 = uilabel(app.UIFigure);
            app.BusyLampLabel_2.Text = ' ver 2.18       ';
            app.Button_SaveFigure_2 = uibutton(app.UIFigure, 'push');
            app.Button_SaveFigure_2.ButtonPushedFcn = createCallbackFcn(app, @Button_SaveFigure_2Pushed, true);
            app.Button_SaveFigure_2.Icon = 'film-strip-with-two-photograms.png';
            app.Button_SaveFigure_2.Text = 'Save Montage';
            app.MontageMovieCheckBox = uicheckbox(app.UIFigure);
            app.MontageMovieCheckBox.ValueChangedFcn = createCallbackFcn(app, @MontageMovieCheckBoxValueChanged, true);
            app.MontageMovieCheckBox.Text = '';
            app.MovieatFPSSpinnerLabel = uilabel(app.UIFigure);
            app.MovieatFPSSpinnerLabel.Enable = 'off';
            app.MovieatFPSSpinnerLabel.Text = 'Movie at FPS';
            app.MovieatFPSSpinner = uispinner(app.UIFigure);
            app.MovieatFPSSpinner.Limits = [1 10];
            app.MovieatFPSSpinner.Enable = 'off';
            app.MovieatFPSSpinner.Value = 1;
            app.ListBox_3Label_2 = uilabel(app.UIFigure);
            app.ListBox_3Label_2.Enable = 'off';
            app.ListBox_3Label_2.Text = 'Weights:';
            app.Button_PrimarySegmentHelp_3 = uibutton(app.UIFigure, 'push');
            app.Button_PrimarySegmentHelp_3.Icon = 'question-sign.png';
            app.Button_PrimarySegmentHelp_3.Enable = 'off';
            app.Button_PrimarySegmentHelp_3.Text = '';
            app.ListBox_3Label_3 = uilabel(app.UIFigure);
            app.ListBox_3Label_3.Text = 'Time Column:';
            app.TimeColumnDropDown = uidropdown(app.UIFigure);
            app.TimeColumnDropDown.Items = {};
            app.TimeColumnDropDown.Value = {};
            app.TimeColumnHelpButton = uibutton(app.UIFigure, 'push');
            app.TimeColumnHelpButton.ButtonPushedFcn = createCallbackFcn(app, @TimeColumnHelpButtonPushed, true);
            app.TimeColumnHelpButton.Icon = 'question-sign.png';
            app.TimeColumnHelpButton.Text = '';
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Enable = 'off';
            app.EditField_6Label.Text = 'Edit Field';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Enable = 'off';
            app.EditField_7Label = uilabel(app.UIFigure);
            app.EditField_7Label.Enable = 'off';
            app.EditField_7Label.Text = 'Edit Field';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Enable = 'off';
            app.EditField_8Label = uilabel(app.UIFigure);
            app.EditField_8Label.Enable = 'off';
            app.EditField_8Label.Text = 'Edit Field';
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.Enable = 'off';
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Enable = 'off';
            app.EditField_9Label.Text = 'Edit Field';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.Enable = 'off';
            app.fromTracktabLabel = uilabel(app.UIFigure);
            app.fromTracktabLabel.Text = 'from Track tab';
            app.fromDisplaytabLabel = uilabel(app.UIFigure);
            app.fromDisplaytabLabel.Text = 'from Display tab';
            app.Button_Track_2 = uibutton(app.UIFigure, 'push');
            app.Button_Track_2.Icon = 'HELIX.png';
            app.Button_Track_2.Text = '';
            app.OrganizingtheworldsscientificalgorithmsLabel = uilabel(app.UIFigure);
            app.OrganizingtheworldsscientificalgorithmsLabel.Text = 'Organizing the world’s scientific algorithms.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end